classdef LASSO_GUI_v2_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        NormalizeCheckBox               matlab.ui.control.CheckBox
        RunLASSOButton                  matlab.ui.control.Button
        SetEditField                    matlab.ui.control.NumericEditField
        SetEditFieldLabel               matlab.ui.control.Label
        SaveresultsCheckBox             matlab.ui.control.CheckBox
        OutputfilepathEditField         matlab.ui.control.EditField
        OutputfilepathEditFieldLabel    matlab.ui.control.Label
        SetbinsizeEditField             matlab.ui.control.NumericEditField
        SetbinsizeEditFieldLabel        matlab.ui.control.Label
        BinningCheckBox                 matlab.ui.control.CheckBox
        AllownegativevaluesCheckBox     matlab.ui.control.CheckBox
        PowercorrectionCheckBox         matlab.ui.control.CheckBox
        BackgroundsubtractionCheckBox   matlab.ui.control.CheckBox
        LoadfrequenciesandpowersButton  matlab.ui.control.Button
        ParameterfileEditField          matlab.ui.control.EditField
        ParameterfileEditFieldLabel     matlab.ui.control.Label
        LoadimageButton                 matlab.ui.control.Button
        ImagestackEditField             matlab.ui.control.EditField
        ImagestackEditFieldLabel        matlab.ui.control.Label
        LoadreferenceButton             matlab.ui.control.Button
        ReferencefileEditField          matlab.ui.control.EditField
        ReferencefileEditFieldLabel     matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        Zprojection                     matlab.ui.control.UIAxes
        ResultTab                       matlab.ui.container.Tab
        LASSO_Result                    matlab.ui.control.UIAxes
        ReferencePlot                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadreferenceButton
        function LoadreferenceButtonPushed(app, event)
            cla(app.ReferencePlot);
            if ~isfile(app.ReferencefileEditField.Value)
                [filename,folder] = uigetfile('*.*');
                CURRENTFILE = fullfile(folder,filename);
                refspectra = importdata(CURRENTFILE);
                app.ReferencefileEditField.Value = CURRENTFILE;
            else
                refspectra = importdata(string(app.ReferencefileEditField.Value));
            end
            refdata = refspectra.data;
            mastercolors = [0 1 1;
                1 1 0;
                1 0 1;
                0 1 0;
                0 0 1;
                1 0 0;
                0 1 0.5;
                0.5 0 1;
                1 0.5 0;
                0.5 1 0;
                0 0.5 1;
                1 0 0.5].*0.8; % 12 possible colors
            
            xl1 = nan; xl2 = nan;
            if width(refdata) > 1
                for i=1:width(refdata)/2
                    xval = refdata(:,2*i-1); yval = refdata(:,2*i);
                    if app.NormalizeCheckBox.Value == 1
                        yval = yval./max(yval);
                    end
                    plot(app.ReferencePlot,xval,yval,'Color',mastercolors(i,:),'LineWidth',3)
                    if isnan(xl1) || min(xval) < xl1
                        xl1 = min(xval);
                    end
                    if isnan(xl2) || max(xval) < xl2
                        xl2 = max(xval);
                    end
                end
            end
            xlim(app.ReferencePlot,[xl1 xl2]);
        end

        % Value changed function: ReferencefileEditField
        function ReferencefileEditFieldValueChanged(app, event)
            % value = app.ReferencefileEditField.Value;
            
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            cla(app.Zprojection);
            if ~isfile(app.ImagestackEditField.Value)
                [filename,folder] = uigetfile('*.*');
                CURRENTFILE = fullfile(folder,filename);
                stack = importdata(CURRENTFILE);
                app.ImagestackEditField.Value = CURRENTFILE;
            else
                stack = double(tiffreadVolume(string(app.ImagestackEditField.Value)));
            end
            
            zproj = max(stack,[],3);
            image(app.Zprojection,zproj,"CDataMapping","scaled");
            xlim(app.Zprojection,[1 width(zproj)]); ylim(app.Zprojection,[1 height(zproj)]);
        end

        % Button pushed function: LoadfrequenciesandpowersButton
        function LoadfrequenciesandpowersButtonPushed(app, event)
            if ~isfile(app.ReferencefileEditField.Value)
                [filename,folder] = uigetfile('*.*');
                CURRENTFILE = fullfile(folder,filename);
                refspectra = importdata(CURRENTFILE);
                app.ReferencefileEditField.Value = CURRENTFILE;
            else
                refspectra = importdata(string(app.ReferencefileEditField.Value));
            end
            refdata = refspectra.data;
            mastercolors = [0 1 1;
                1 1 0;
                1 0 1;
                0 1 0;
                0 0 1;
                1 0 0;
                0 1 0.5;
                0.5 0 1;
                1 0.5 0;
                0.5 1 0;
                0 0.5 1;
                1 0 0.5].*0.8; % 12 possible colors

            if ~isfile(app.ParameterfileEditField.Value)
                [filename,folder] = uigetfile('*.*');
                CURRENTFILE = fullfile(folder,filename);
                parameters = importdata(CURRENTFILE);
                app.ParameterfileEditField.Value = CURRENTFILE;
            else
                parameters = importdata(string(app.ParameterfileEditField.Value));
            end
            
            freq = parameters.data(:,1);
            % power = parameters.data(:,2);
            cleanref = zeros(length(freq),width(refdata)/2);
            for i=1:width(cleanref)
                xval = refdata(:,2*i-1); yval = refdata(:,2*i);
                if app.NormalizeCheckBox.Value == 1
                    yval = yval./max(yval);
                end
                cleanref(:,i) = spline(xval,yval,freq);
                plot(app.ReferencePlot,freq,cleanref(:,i),'o','MarkerSize',8,'Color',mastercolors(i,:),'LineWidth',2)
            end
        end

        % Button pushed function: RunLASSOButton
        function RunLASSOButtonPushed(app, event)
            cla(app.LASSO_Result);
            if ~isfile(app.ReferencefileEditField.Value)
                [filename,folder] = uigetfile('*.*');
                CURRENTFILE = fullfile(folder,filename);
                refspectra = importdata(CURRENTFILE);
                app.ReferencefileEditField.Value = CURRENTFILE;
            else
                refspectra = importdata(string(app.ReferencefileEditField.Value));
            end
            refdata = refspectra.data;
            mastercolors = [0 1 1;
                1 1 0;
                1 0 1;
                0 1 0;
                0 0 1;
                1 0 0;
                0 1 0.5;
                0.5 0 1;
                1 0.5 0;
                0.5 1 0;
                0 0.5 1;
                1 0 0.5].*0.8; % 12 possible colors

            if ~isfile(app.ImagestackEditField.Value)
                [filename,folder] = uigetfile('*.*');
                CURRENTFILE = fullfile(folder,filename);
                stack = importdata(CURRENTFILE);
                app.ImagestackEditField.Value = CURRENTFILE;
            else
                stack = double(tiffreadVolume(string(app.ImagestackEditField.Value)));
            end

            if ~isfile(app.ParameterfileEditField.Value)
                [filename,folder] = uigetfile('*.*');
                CURRENTFILE = fullfile(folder,filename);
                parameters = importdata(CURRENTFILE);
                app.ParameterfileEditField.Value = CURRENTFILE;
            else
                parameters = importdata(string(app.ParameterfileEditField.Value));
            end
            freq = parameters.data(:,1);
            if width(parameters.data) > 1
                power = parameters.data(:,2);
            else
                power = ones(length(freq),1);
            end
            cleanref = zeros(length(freq),width(refdata)/2);
            for i=1:width(cleanref)
                xval = refdata(:,2*i-1); yval = refdata(:,2*i);
                if app.NormalizeCheckBox.Value == 1
                    yval = yval./max(yval);
                end
                cleanref(:,i) = spline(xval,yval,freq);
            end

            copt = zeros(size(stack,1),size(stack,2),12);
            binsize = app.SetbinsizeEditField.Value;
            if app.BinningCheckBox.Value == 0
                binsize = 0;
            end
            basecorrect = app.BackgroundsubtractionCheckBox.Value;
            powercorrect = app.PowercorrectionCheckBox.Value;
            nonnegative = app.AllownegativevaluesCheckBox.Value;

            for iii = 1:size(stack,1)
                for jjj = 1:size(stack,2)
                    % NaN check
                    if isnan(stack(iii,jjj,1))
                        copt(iii,jjj,:) = 0;
                    else
                        % Load spectrum with binning
                        istart = max(iii-binsize,1); iend = min(iii+binsize,size(stack,1));
                        jstart = max(jjj-binsize,1); jend = min(jjj+binsize,size(stack,2));
                        pixeldata = squeeze(mean(stack(istart:iend,jstart:jend,:),[1 2]));
        
                        if powercorrect == 1 %power correction
                            pixeldata = pixeldata./(power/max(power)); %
                        end
        
                        if basecorrect == 1 % baseline correction
                            pixeldata = pixeldata-min(pixeldata);
                        end
        
                        % Run LASSO
                        copt(iii,jjj,1:width(cleanref)) = lasso(cleanref,pixeldata,'lambda',app.SetEditField.Value);
        
                        if nonnegative == 0
                            for i=1:length(copt(iii,jjj,:))
                                if copt(iii,jjj,i) < 0
                                    copt(iii,jjj,i) = 0;
                                end
                            end
                        end
                    end
                end
            end
        
            % Begin building image (weird artifacts if attempted in a loop)
 
            % Convert components to images - ordered by reference spectra
            Comp1 = copt(:,:,1); Comp2 = copt(:,:,2); Comp3 = copt(:,:,3); Comp4 = copt(:,:,4);
            Comp5 = copt(:,:,5); Comp6 = copt(:,:,6); Comp7 = copt(:,:,7); Comp8 = copt(:,:,8);
            Comp9 = copt(:,:,9); Comp10 = copt(:,:,10); Comp11 = copt(:,:,11); Comp12 = copt(:,:,12);
        
            % Convert data to scaled pixel intensities (0-255) for image viewing
            ScComp1 = imadjust(uint8(255.*(Comp1 - min(Comp1(:) ))/(max(Comp1(:))-min(Comp1(:) ))));
            ScComp2 = imadjust(uint8(255.*(Comp2 - min(Comp2(:) ))/(max(Comp2(:))-min(Comp2(:) ))));
            ScComp3 = imadjust(uint8(255.*(Comp3 - min(Comp3(:) ))/(max(Comp3(:))-min(Comp3(:) ))));
            ScComp4 = imadjust(uint8(255.*(Comp4 - min(Comp4(:) ))/(max(Comp4(:))-min(Comp4(:) ))));
            ScComp5 = imadjust(uint8(255.*(Comp5 - min(Comp5(:) ))/(max(Comp5(:))-min(Comp5(:) ))));
            ScComp6 = imadjust(uint8(255.*(Comp6 - min(Comp6(:) ))/(max(Comp6(:))-min(Comp6(:) ))));
            ScComp7 = imadjust(uint8(255.*(Comp7 - min(Comp7(:) ))/(max(Comp7(:))-min(Comp7(:) ))));
            ScComp8 = imadjust(uint8(255.*(Comp8 - min(Comp8(:) ))/(max(Comp8(:))-min(Comp8(:) ))));
            ScComp9 = imadjust(uint8(255.*(Comp9 - min(Comp9(:) ))/(max(Comp9(:))-min(Comp9(:) ))));
            ScComp10 = imadjust(uint8(255.*(Comp10 - min(Comp10(:) ))/(max(Comp10(:))-min(Comp10(:) ))));
            ScComp11 = imadjust(uint8(255.*(Comp11 - min(Comp11(:) ))/(max(Comp11(:))-min(Comp11(:) ))));
            ScComp12 = imadjust(uint8(255.*(Comp12 - min(Comp12(:) ))/(max(Comp12(:))-min(Comp12(:) ))));
        
            % Assign colors
            rgbComp1 = cat(3, ScComp1*mastercolors(1,1), ScComp1*mastercolors(1,2), ScComp1*mastercolors(1,3));
            rgbComp2 = cat(3, ScComp2*mastercolors(2,1), ScComp2*mastercolors(2,2), ScComp2*mastercolors(2,3));
            rgbComp3 = cat(3, ScComp3*mastercolors(3,1), ScComp3*mastercolors(3,2), ScComp3*mastercolors(3,3));
            rgbComp4 = cat(3, ScComp4*mastercolors(4,1), ScComp4*mastercolors(4,2), ScComp4*mastercolors(4,3));
            rgbComp5 = cat(3, ScComp5*mastercolors(5,1), ScComp5*mastercolors(5,2), ScComp5*mastercolors(5,3));
            rgbComp6 = cat(3, ScComp6*mastercolors(6,1), ScComp6*mastercolors(6,2), ScComp6*mastercolors(6,3));
            rgbComp7 = cat(3, ScComp7*mastercolors(7,1), ScComp7*mastercolors(7,2), ScComp7*mastercolors(7,3));
            rgbComp8 = cat(3, ScComp8*mastercolors(8,1), ScComp8*mastercolors(8,2), ScComp8*mastercolors(8,3));
            rgbComp9 = cat(3, ScComp9*mastercolors(9,1), ScComp9*mastercolors(9,2), ScComp9*mastercolors(9,3));
            rgbComp10 = cat(3, ScComp10*mastercolors(10,1), ScComp10*mastercolors(10,2), ScComp10*mastercolors(10,3));
            rgbComp11 = cat(3, ScComp11*mastercolors(11,1), ScComp11*mastercolors(11,2), ScComp11*mastercolors(11,3));
            rgbComp12 = cat(3, ScComp12*mastercolors(12,1), ScComp12*mastercolors(12,2), ScComp12*mastercolors(12,3));
        
            % Build and show composite
            C_disp = rgbComp1+rgbComp2+rgbComp3+rgbComp4+...
                rgbComp5+rgbComp6+rgbComp7+rgbComp8+...
                rgbComp9+rgbComp10+rgbComp11+rgbComp12;
        
            imshow(C_disp,'Parent',app.LASSO_Result);

            % Save results
            if app.SaveresultsCheckBox.Value == 1
                if ~contains(app.OutputfilepathEditField.Value,'/') || ~contains(app.OutputfilepathEditField.Value,'\')
                    [filename5,folder5] = uiputfile('Unmixed_results.tif');
                    SAVEFILE = fullfile(folder5,filename5);
                    app.OutputfilepathEditField.Value = SAVEFILE;
                end
                fname = string(app.OutputfilepathEditField.Value);
                TIF = Tiff(fname, 'w');
                for k = 1:width(cleanref)
                    % Set TIFF tags for floating-point storage
                    tagStruct.ImageLength = size(copt, 1);
                    tagStruct.ImageWidth = size(copt, 2);
                    tagStruct.Photometric = Tiff.Photometric.MinIsBlack;
                    tagStruct.BitsPerSample = 64;
                    tagStruct.SamplesPerPixel = 1;
                    tagStruct.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
                    tagStruct.SampleFormat = Tiff.SampleFormat.IEEEFP; % Floating-point format
                    tagStruct.Software = 'MATLAB';
                    TIF.setTag(tagStruct);
                    TIF.write(copt(:, :, k));
                    if k < size(copt, 3)
                       TIF.writeDirectory(); % Create a new directory for the next slice
                    end
                end
                TIF.close();
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ReferencefileEditFieldLabel = uilabel(app.LeftPanel);
            app.ReferencefileEditFieldLabel.Text = 'Reference file';
            app.ReferencefileEditField = uieditfield(app.LeftPanel, 'text');
            app.ReferencefileEditField.ValueChangedFcn = createCallbackFcn(app, @ReferencefileEditFieldValueChanged, true);
            app.LoadreferenceButton = uibutton(app.LeftPanel, 'push');
            app.LoadreferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadreferenceButtonPushed, true);
            app.LoadreferenceButton.Text = '1. Load reference';
            app.ImagestackEditFieldLabel = uilabel(app.LeftPanel);
            app.ImagestackEditFieldLabel.Text = 'Image stack';
            app.ImagestackEditField = uieditfield(app.LeftPanel, 'text');
            app.LoadimageButton = uibutton(app.LeftPanel, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = '2. Load image';
            app.ParameterfileEditFieldLabel = uilabel(app.LeftPanel);
            app.ParameterfileEditFieldLabel.Text = 'Parameter file';
            app.ParameterfileEditField = uieditfield(app.LeftPanel, 'text');
            app.LoadfrequenciesandpowersButton = uibutton(app.LeftPanel, 'push');
            app.LoadfrequenciesandpowersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfrequenciesandpowersButtonPushed, true);
            app.LoadfrequenciesandpowersButton.Text = '3. Load frequencies and powers';
            app.BackgroundsubtractionCheckBox = uicheckbox(app.LeftPanel);
            app.BackgroundsubtractionCheckBox.Text = 'Background subtraction';
            app.BackgroundsubtractionCheckBox.Value = true;
            app.PowercorrectionCheckBox = uicheckbox(app.LeftPanel);
            app.PowercorrectionCheckBox.Text = 'Power correction';
            app.PowercorrectionCheckBox.Value = true;
            app.AllownegativevaluesCheckBox = uicheckbox(app.LeftPanel);
            app.AllownegativevaluesCheckBox.Text = 'Allow negative values';
            app.BinningCheckBox = uicheckbox(app.LeftPanel);
            app.BinningCheckBox.Text = 'Binning?';
            app.SetbinsizeEditFieldLabel = uilabel(app.LeftPanel);
            app.SetbinsizeEditFieldLabel.Text = 'Set binsize';
            app.SetbinsizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SetbinsizeEditField.Limits = [0 Inf];
            app.SetbinsizeEditField.RoundFractionalValues = 'on';
            app.OutputfilepathEditFieldLabel = uilabel(app.LeftPanel);
            app.OutputfilepathEditFieldLabel.Text = 'Output file path';
            app.OutputfilepathEditField = uieditfield(app.LeftPanel, 'text');
            app.SaveresultsCheckBox = uicheckbox(app.LeftPanel);
            app.SaveresultsCheckBox.Text = 'Save results';
            app.SetEditFieldLabel = uilabel(app.LeftPanel);
            app.SetEditFieldLabel.Text = 'Set λ';
            app.SetEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SetEditField.Limits = [0 Inf];
            app.SetEditField.Value = 0.7;
            app.RunLASSOButton = uibutton(app.LeftPanel, 'push');
            app.RunLASSOButton.ButtonPushedFcn = createCallbackFcn(app, @RunLASSOButtonPushed, true);
            app.RunLASSOButton.Text = '4. Run LASSO';
            app.NormalizeCheckBox = uicheckbox(app.LeftPanel);
            app.NormalizeCheckBox.Text = 'Normalize?';
            app.RightPanel = uipanel(app.GridLayout);
            app.ReferencePlot = uiaxes(app.RightPanel);
            title(app.ReferencePlot, 'Reference spectra')
            xlabel(app.ReferencePlot, 'Frequency (cm^{–1})')
            ylabel(app.ReferencePlot, 'Intensity (AU)')
            zlabel(app.ReferencePlot, 'Z')
            app.TabGroup = uitabgroup(app.RightPanel);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.Zprojection = uiaxes(app.InputTab);
            title(app.Zprojection, 'Input stack (Z-projection)')
            xlabel(app.Zprojection, 'X')
            ylabel(app.Zprojection, 'Y')
            zlabel(app.Zprojection, 'Intensity (AU)')
            app.ResultTab = uitab(app.TabGroup);
            app.ResultTab.Title = 'Result';
            app.LASSO_Result = uiaxes(app.ResultTab);
            title(app.LASSO_Result, 'LASSO Unmixing')
            xlabel(app.LASSO_Result, 'X')
            ylabel(app.LASSO_Result, 'Y')
            zlabel(app.LASSO_Result, 'Intensity (AU)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LASSO_GUI_v2_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end